classdef A2E_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutomodetosetinputparametersDropDown  matlab.ui.control.DropDown
        AutomodetosetinputparametersLabel  matlab.ui.control.Label
        DropDown4mean_elev              matlab.ui.control.DropDown
        DropDown_2Label_2               matlab.ui.control.Label
        MeanelevationmEditField         matlab.ui.control.NumericEditField
        MeanelevationmEditFieldLabel    matlab.ui.control.Label
        DropDown4tempatthemeanelev      matlab.ui.control.DropDown
        DropDownLabel_2                 matlab.ui.control.Label
        TemperatureatthemeanelevationoCEditField  matlab.ui.control.NumericEditField
        TemperatureatthemeanelevationoCEditFieldLabel  matlab.ui.control.Label
        DropDown4geothermics            matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        InitialgeothermalgradientoCkmEditField  matlab.ui.control.NumericEditField
        InitialgeothermalgradientoCkmEditFieldLabel  matlab.ui.control.Label
        DropDown4exhum                  matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        CloseallpreviousplotsButton     matlab.ui.control.StateButton
        click2selectoutputfolder        matlab.ui.control.Button
        selectoutputfolder              matlab.ui.control.EditField
        NotesinputelevationsshouldbeinmetersLabel  matlab.ui.control.Label
        DepthintervaldzkmEditField      matlab.ui.control.NumericEditField
        DepthintervaldzkmEditFieldLabel  matlab.ui.control.Label
        Thermaldiffusivitykm2MyrEditField  matlab.ui.control.NumericEditField
        Thermaldiffusivitykm2MyrLabel   matlab.ui.control.Label
        LapserateKkmEditField           matlab.ui.control.NumericEditField
        LapserateoCkmLabel              matlab.ui.control.Label
        NumberofiterationEditField      matlab.ui.control.NumericEditField
        NumberofiterationEditFieldLabel  matlab.ui.control.Label
        ModelblockthicknesskmEditField  matlab.ui.control.NumericEditField
        ModelblockthicknesskmLabel      matlab.ui.control.Label
        click2loadDEM                   matlab.ui.control.Button
        click2loadAGES                  matlab.ui.control.Button
        selectAGEdata                   matlab.ui.control.EditField
        selectDEMdata                   matlab.ui.control.EditField
        PlottheaposteriorresultsButton  matlab.ui.control.Button
        ClicktoRUNthemodelButton        matlab.ui.control.Button
        TimeintervalsizeMaEditField     matlab.ui.control.NumericEditField
        TimeintervalsizeMaLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ThepriorexhumationratekmMyrEditField  matlab.ui.control.NumericEditField
        TheaprioriexhumationratekmMyrEditField_error  matlab.ui.control.NumericEditField
        TheaprioriexhumationratekmmyrLabel  matlab.ui.control.Label
        ModelstartingtimeMaEditField    matlab.ui.control.NumericEditField
        ModelstartingtimeMaLabel        matlab.ui.control.Label
        ModerngeothermalgradientoCkmEditField  matlab.ui.control.NumericEditField
        InitialgeothermalgradientKkmLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: click2loadDEM
        function click2loadDEMButtonPushed(app, event)
%             [dem_data_filename,dem_data_path] = uigetfile({'*.txt, *.csv'},'File Selector');  %open a mat file
                if isdeployed 
			        % User is running an executable in standalone mode. 
			        [~ , result] = system('set PATH');
			        executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
		        else
			        % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                    tmp_path = mfilename('fullpath');
                    [executableFolder , ~ , ~] = fileparts(tmp_path);
                end 

                [dem_data_filename,dem_data_path] = uigetfile(strcat(executableFolder,filesep,"data4testing",filesep,"Fitzgerald1995",filesep,"DEM_Fitzgerald1995.csv"),'File Selector');  %open a mat file
            
                if isequal(dem_data_filename,0)
                   disp('User selected Cancel');
                else
                   disp(['User selected ', fullfile(dem_data_path,dem_data_filename)]);
                end
            dem_data = load(fullfile(dem_data_path,dem_data_filename));
            dem_data = sortrows(dem_data,[1,2],{ 'ascend' 'descend'});
            longi_range = [min(dem_data(:,1)) max(dem_data(:,1))]; nx = length(unique(dem_data(:,1)));
            lati_range = [min(dem_data(:,2)) max(dem_data(:,2))]; ny = length(unique(dem_data(:,2)));
            dem_data2 = reshape(dem_data(:,3),[ny, nx]);
            ax1 = app.UIAxes;
            imagesc(app.UIAxes,longi_range,lati_range, flipud(dem_data2));
            set(ax1,'ydir','normal')
            app.selectDEMdata.Value = fullfile(dem_data_path,dem_data_filename);
%             disp(num2str(app.TimeintervalsizeMaEditField.Value));
        end

        % Button pushed function: click2loadAGES
        function click2loadAGESPushed(app, event)
%             [age_data_filename,age_data_path] = uigetfile({'*.txt, *.csv'},'File Selector');  %open a mat file
            if isdeployed 
			        % User is running an executable in standalone mode. 
			        [~ , result] = system('set PATH');
			        executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
		        else
			        % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                    tmp_path = mfilename('fullpath');
                    [executableFolder , ~ , ~] = fileparts(tmp_path);
            end 
            
            [age_data_filename,age_data_path] = uigetfile(strcat(executableFolder,filesep,"data4testing",filesep,"Fitzgerald1995",filesep,"DATA_Fitzgerald1995.csv"),'File Selector');  %open a mat file 
            if isequal(age_data_filename,0)
                   disp('User selected Cancel');
                else
                   disp(['User selected ', fullfile(age_data_path,age_data_filename)]);
            end 
            age_data = readtable(fullfile(age_data_path,age_data_filename));
            hold (app.UIAxes, 'on')
            ax2 = app.UIAxes;
            scatter(ax2,age_data{:,3},age_data{:,2}, 30,'o','filled','markerfacecolor','w','markeredgecolor','k')
%             scatter(ax2,age_data{:,3},age_data{:,2}, 30, age_data{:,5},'o','filled','markeredgecolor','k');
%             caxis(ax2, [min(age_data{:,5})*.8 max(age_data{:,5})*1.2])
%             c = colorbar(ax2);
%             c.Label.String = 'Age';
            app.selectAGEdata.Value = fullfile(age_data_path,age_data_filename);

            scatter(app.UIAxes2,age_data{:,5},age_data{:,4}, 30,'o','filled','markerfacecolor','w','markeredgecolor','k')
        end

        % Value changed function: ModerngeothermalgradientoCkmEditField
        function ModerngeothermalgradientoCkmEditFieldValueChanged(app, event)
            Initialgeothermalgradient = app.ModerngeothermalgradientoCkmEditField.Value;
            
        end

        % Value changed function: ModelstartingtimeMaEditField
        function ModelstartingtimeMaEditFieldValueChanged(app, event)
            % age_data = readtable(app.selectAGEdata.Value);
            % if app.AutomodetosetinputparametersDropDown.Value == "Yes"
            %     app.app.ModelstartingtimeMaEditField.Value.Value = ceil(age_data{:,5}/10)*10;
            %     app.TimeintervalsizeMaEditField.FontColor = [0.5 0.5 0.5];
            % else
            % end
            Modelstartingtime = app.ModelstartingtimeMaEditField.Value;
        end

        % Value changed function: ThepriorexhumationratekmMyrEditField
        function ThepriorexhumationratekmMyrEditFieldValueChanged(app, event)
            Theaprioriexhumationrate = app.ThepriorexhumationratekmMyrEditField.Value;
            
        end

        % Value changed function: 
        % TheaprioriexhumationratekmMyrEditField_error
        function TheaprioriexhumationratekmMyrEditField_errorValueChanged(app, event)
            Theaprioriexhumationrate_error = app.TheaprioriexhumationratekmMyrEditField_error.Value;
            
        end

        % Value changed function: TimeintervalsizeMaEditField
        function TimeintervalsizeMaEditFieldValueChanged(app, event)
            
            % if app.AutomodetosetinputparametersDropDown.Value == "Yes"
            %     app.TimeintervalsizeMaEditField.Value = fix(app.ModelstartingtimeMaEditField.Value/10);
            %     app.TimeintervalsizeMaEditField.FontColor = [0.5 0.5 0.5];
            % 
            % else
            % end
            Timeintervalsize = app.TimeintervalsizeMaEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: selectDEMdata
        function selectDEMdataValueChanged(app, event)
            value = app.selectDEMdata.Value;
            
        end

        % Value changed function: selectAGEdata
        function selectAGEdataValueChanged(app, event)
            value = app.selectAGEdata.Value;
            
        end

        % Button pushed function: ClicktoRUNthemodelButton
        function ClicktoRUNthemodelButtonPushed(app, event)
            G_present_authority = app.ModerngeothermalgradientoCkmEditField.Value; % if G_present_authority is defined, it will be used in the following calculations
            if app.DropDown4geothermics.Value == "Auto"
            else
                Tgrd_initial_authority = app.InitialgeothermalgradientoCkmEditField.Value;
            end
            start_time_authority = app.ModelstartingtimeMaEditField.Value; % if start_time_authority is defined, it will be used in the following calculations and the iterations for this value will not be run
            dt2plot_authority = app.TimeintervalsizeMaEditField.Value; % if dt2plot_authority is defined, it will be used in the following calculations
            if app.DropDown4exhum.Value == "Auto"
                n_model4steps2N3 = 20;
            else
                n_model4steps2N3 = 1;
                e0_pr_authority = app.ThepriorexhumationratekmMyrEditField.Value;
                sigma0_pr_authority = app.TheaprioriexhumationratekmMyrEditField_error.Value;
            end

            if app.DropDown4mean_elev.Value == "Auto"
            else
                h_mean_authority = app.MeanelevationmEditField.Value/1000; % mean elevation
            end

            if app.DropDown4tempatthemeanelev.Value == "Auto"
            else
                T0_authority = app.TemperatureatthemeanelevationoCEditField.Value; % mean elevation
            end
            % % constants and parameter setup
            dem_data = load(app.selectDEMdata.Value);
            zmax = app.ModelblockthicknesskmEditField.Value;  % maximum depth of crustal block
            dz = app.DepthintervaldzkmEditField.Value;
            Nz = fix(zmax/dz) +1;  % 
            ite = app.NumberofiterationEditField.Value;   % iteration
            lapse_rate = app.LapserateKkmEditField.Value; % C/km
            kappa = app.Thermaldiffusivitykm2MyrEditField.Value; % thermal diffusivity, km2/Myr 
            removing_previous_results = 0;
            misfit_mode = 0; %1 for using age misfit, 0 for (misfit_age + misfit_G/sqrt(n)), and 2 for using (misfit_age + misfit_G/n), and 3 for (misfit_age + misfit_G)
            error_type = 0;
            consider_basal_flux = 1; % 1 and 0 means considering or not 

            codeFolder = pwd;
            
            % if ispc
            %     idcs = strfind(codeFolder,filesep);
            %     currentFolder = codeFolder(1:idcs(end)-1);
            %     idcs = strfind(app.selectAGEdata.Value,filesep);
            %     % input_data_folder = app.selectAGEdata.Value(1:idcs(end)-1);
            % elseif ismac
                idcs = strfind(codeFolder,filesep);
                currentFolder = codeFolder(1:idcs(end)-1);
                idcs = strfind(app.selectAGEdata.Value,filesep);
                % input_data_folder = app.selectAGEdata.Value(1:idcs(end)-1);
            % else
            % end
            which_one2process_input = app.selectAGEdata.Value(1:idcs(end)-1);
            input_data_folder = extractBetween(string(app.selectDEMdata.Value),"DEM_",".csv");
            output_folder = app.selectoutputfolder.Value;
            dem_data_file = app.selectDEMdata.Value;
            age_data_file = app.selectAGEdata.Value;
            master4GUI;
            cd (codeFolder)
        end

        % Button pushed function: click2selectoutputfolder
        function click2selectoutputfolderButtonPushed(app, event)
            if isdeployed 
			        % User is running an executable in standalone mode. 
			        [~ , result] = system('set PATH');
			        executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
		        else
			        % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                    tmp_path = mfilename('fullpath');
                    [executableFolder , ~ , ~] = fileparts(tmp_path);
            end 
            
            [selpath] = uigetdir(strcat(executableFolder,filesep,"output4GUIapp"));  %open a mat file
            app.selectoutputfolder.Value = selpath;
        end

        % Value changed function: selectoutputfolder
        function selectoutputfolderValueChanged(app, event)
            value = app.selectoutputfolder.Value;
            
        end

        % Button pushed function: PlottheaposteriorresultsButton
        function PlottheaposteriorresultsButtonPushed(app, event)
            codeFolder = pwd;
            % if ispc
            %     idcs = strfind(codeFolder,filesep);
            %     currentFolder = codeFolder(1:idcs(end)-1);
            %     idcs = strfind(app.selectAGEdata.Value,filesep);
            %     input_data_folder = app.selectAGEdata.Value(1:idcs(end)-1);
            %     colorfolder = fullfile(codeFolder,"othercolor","othercolor");
            % elseif ismac
                idcs = strfind(codeFolder,filesep);
                currentFolder = codeFolder(1:idcs(end)-1);
                idcs = strfind(app.selectAGEdata.Value,filesep);
                input_data_folder = app.selectAGEdata.Value(1:idcs(end)-1);
%                 colorfolder = fullfile(codeFolder,"othercolor","othercolor");
            % else
            % end
            output_folder = app.selectoutputfolder.Value;
            % which_one2process_input = app.selectAGEdata.Value(idcs(end-1)+1:idcs(end)-1);
            ite = app.NumberofiterationEditField.Value;   % iteration
            if app.DropDown4geothermics.Value == "Auto" && app.DropDown4exhum.Value == "Auto"
                whichfolder2plot = 0;
                n_model4steps2N3 = 20;
            else
                whichfolder2plot = 1;
            end
            
            plot_output_figures4GUI;
            
        end

        % Value changed function: CloseallpreviousplotsButton
        function CloseallpreviousplotsButtonValueChanged(app, event)
            value = app.CloseallpreviousplotsButton.Value;
            close all
        end

        % Value changed function: DropDown4exhum
        function DropDown4exhumValueChanged(app, event)
            if app.DropDown4exhum.Value == "Auto"
                app.ThepriorexhumationratekmMyrEditField.FontColor = [0.5 0.5 0.5];
                app.TheaprioriexhumationratekmMyrEditField_error.FontColor = [0.5 0.5 0.5];
            else
                app.ThepriorexhumationratekmMyrEditField.FontColor = [0. 0. 0.];
                app.TheaprioriexhumationratekmMyrEditField_error.FontColor = [0. 0. 0.];
            end
            
        end

        % Value changed function: DropDown4geothermics
        function DropDown4geothermicsValueChanged(app, event)
            if app.DropDown4geothermics.Value == "Auto"
                app.InitialgeothermalgradientoCkmEditField.FontColor= [0.5 0.5 0.5];
            else
                app.InitialgeothermalgradientoCkmEditField.FontColor= [0. 0. 0.];
            end

        end

        % Value changed function: AutomodetosetinputparametersDropDown
        function AutomodetosetinputparametersDropDownValueChanged(app, event)

        end

        % Drop down opening function: AutomodetosetinputparametersDropDown
        function AutomodetosetinputparametersDropDownOpening(app, event)
            if app.AutomodetosetinputparametersDropDown.Value == "Yes"
                app.DropDown4geothermics.Value = "Auto";
                app.InitialgeothermalgradientoCkmEditField.FontColor = [0.5 0.5 0.5];
                app.DropDown4exhum.Value = "Auto";
                app.ThepriorexhumationratekmMyrEditField.FontColor = [0.5 0.5 0.5];
                app.TheaprioriexhumationratekmMyrEditField_error.FontColor = [0.5 0.5 0.5];
                app.DropDown4mean_elev.Value = "Auto";
                app.MeanelevationmEditField.FontColor= [0.5 0.5 0.5];
                app.DropDown4tempatthemeanelev.Value = "Auto";
                app.TemperatureatthemeanelevationoCEditField.FontColor= [0.5 0.5 0.5];
            else
            end
        end

        % Value changed function: InitialgeothermalgradientoCkmEditField
        function InitialgeothermalgradientoCkmEditFieldValueChanged(app, event)
            value = app.InitialgeothermalgradientoCkmEditField.Value;
            
        end

        % Value changed function: MeanelevationmEditField
        function MeanelevationmEditFieldValueChanged(app, event)

            
        end

        % Value changed function: TemperatureatthemeanelevationoCEditField
        function TemperatureatthemeanelevationoCEditFieldValueChanged(app, event)
            value = app.TemperatureatthemeanelevationoCEditField.Value;
            
        end

        % Value changed function: DropDown4tempatthemeanelev
        function DropDown4tempatthemeanelevValueChanged(app, event)

            if app.DropDown4tempatthemeanelev.Value == "Auto"
                app.TemperatureatthemeanelevationoCEditField.FontColor= [0.5 0.5 0.5];
            else
                app.TemperatureatthemeanelevationoCEditField.FontColor= [0. 0. 0.];
            end
        end

        % Value changed function: DropDown4mean_elev
        function DropDown4mean_elevValueChanged(app, event)

            if app.DropDown4mean_elev.Value == "Auto"
                app.MeanelevationmEditField.FontColor= [0.5 0.5 0.5];
            else
                app.MeanelevationmEditField.FontColor= [0. 0. 0.];
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'DEM and sample locations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Age - elevation relationship')
            xlabel(app.UIAxes2, 'Age')
            ylabel(app.UIAxes2, 'Elevation')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.InitialgeothermalgradientKkmLabel = uilabel(app.UIFigure);
            app.InitialgeothermalgradientKkmLabel.Text = {'Modern geothermal'; 'gradient (oC/km):'};
            app.ModerngeothermalgradientoCkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModerngeothermalgradientoCkmEditField.ValueChangedFcn = createCallbackFcn(app, @ModerngeothermalgradientoCkmEditFieldValueChanged, true);
            app.ModerngeothermalgradientoCkmEditField.Value = 38.9;
            app.ModelstartingtimeMaLabel = uilabel(app.UIFigure);
            app.ModelstartingtimeMaLabel.Text = 'Model starting time (Ma):';
            app.ModelstartingtimeMaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModelstartingtimeMaEditField.ValueChangedFcn = createCallbackFcn(app, @ModelstartingtimeMaEditFieldValueChanged, true);
            app.ModelstartingtimeMaEditField.Value = 25;
            app.TheaprioriexhumationratekmmyrLabel = uilabel(app.UIFigure);
            app.TheaprioriexhumationratekmmyrLabel.Text = 'The prior exhumation rate (km/Myr):';
            app.TheaprioriexhumationratekmMyrEditField_error = uieditfield(app.UIFigure, 'numeric');
            app.TheaprioriexhumationratekmMyrEditField_error.ValueChangedFcn = createCallbackFcn(app, @TheaprioriexhumationratekmMyrEditField_errorValueChanged, true);
            app.TheaprioriexhumationratekmMyrEditField_error.Value = 0.15;
            app.ThepriorexhumationratekmMyrEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThepriorexhumationratekmMyrEditField.ValueChangedFcn = createCallbackFcn(app, @ThepriorexhumationratekmMyrEditFieldValueChanged, true);
            app.ThepriorexhumationratekmMyrEditField.Value = 0.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+/-';
            app.TimeintervalsizeMaLabel = uilabel(app.UIFigure);
            app.TimeintervalsizeMaLabel.Text = 'Time interval size (Ma):';
            app.TimeintervalsizeMaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeintervalsizeMaEditField.ValueChangedFcn = createCallbackFcn(app, @TimeintervalsizeMaEditFieldValueChanged, true);
            app.TimeintervalsizeMaEditField.Value = 2.5;
            app.ClicktoRUNthemodelButton = uibutton(app.UIFigure, 'push');
            app.ClicktoRUNthemodelButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoRUNthemodelButtonPushed, true);
            app.ClicktoRUNthemodelButton.Text = 'Click to RUN the model';
            app.PlottheaposteriorresultsButton = uibutton(app.UIFigure, 'push');
            app.PlottheaposteriorresultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlottheaposteriorresultsButtonPushed, true);
            app.PlottheaposteriorresultsButton.Text = 'Plot the a posterior results';
            app.selectDEMdata = uieditfield(app.UIFigure, 'text');
            app.selectDEMdata.ValueChangedFcn = createCallbackFcn(app, @selectDEMdataValueChanged, true);
            app.selectAGEdata = uieditfield(app.UIFigure, 'text');
            app.selectAGEdata.ValueChangedFcn = createCallbackFcn(app, @selectAGEdataValueChanged, true);
            app.click2loadAGES = uibutton(app.UIFigure, 'push');
            app.click2loadAGES.ButtonPushedFcn = createCallbackFcn(app, @click2loadAGESPushed, true);
            app.click2loadAGES.Text = 'click to load ages';
            app.click2loadDEM = uibutton(app.UIFigure, 'push');
            app.click2loadDEM.ButtonPushedFcn = createCallbackFcn(app, @click2loadDEMButtonPushed, true);
            app.click2loadDEM.Text = 'click to load DEM';
            app.ModelblockthicknesskmLabel = uilabel(app.UIFigure);
            app.ModelblockthicknesskmLabel.Text = 'Model block thickness (km):';
            app.ModelblockthicknesskmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModelblockthicknesskmEditField.Value = 80;
            app.NumberofiterationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofiterationEditFieldLabel.Text = 'Number of iteration:';
            app.NumberofiterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofiterationEditField.Value = 5;
            app.LapserateoCkmLabel = uilabel(app.UIFigure);
            app.LapserateoCkmLabel.Text = 'Lapse rate (oC/km):';
            app.LapserateKkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LapserateKkmEditField.Value = 6;
            app.Thermaldiffusivitykm2MyrLabel = uilabel(app.UIFigure);
            app.Thermaldiffusivitykm2MyrLabel.Text = 'Thermal diffusivity (km2/Myr):';
            app.Thermaldiffusivitykm2MyrEditField = uieditfield(app.UIFigure, 'numeric');
            app.Thermaldiffusivitykm2MyrEditField.Value = 30;
            app.DepthintervaldzkmEditFieldLabel = uilabel(app.UIFigure);
            app.DepthintervaldzkmEditFieldLabel.Text = 'Depth interval (dz) (km):';
            app.DepthintervaldzkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthintervaldzkmEditField.Value = 0.5;
            app.NotesinputelevationsshouldbeinmetersLabel = uilabel(app.UIFigure);
            app.NotesinputelevationsshouldbeinmetersLabel.Text = 'Notes: input elevations should be in meters';
            app.selectoutputfolder = uieditfield(app.UIFigure, 'text');
            app.selectoutputfolder.ValueChangedFcn = createCallbackFcn(app, @selectoutputfolderValueChanged, true);
            app.click2selectoutputfolder = uibutton(app.UIFigure, 'push');
            app.click2selectoutputfolder.ButtonPushedFcn = createCallbackFcn(app, @click2selectoutputfolderButtonPushed, true);
            app.click2selectoutputfolder.Text = 'Select output folder';
            app.CloseallpreviousplotsButton = uibutton(app.UIFigure, 'state');
            app.CloseallpreviousplotsButton.ValueChangedFcn = createCallbackFcn(app, @CloseallpreviousplotsButtonValueChanged, true);
            app.CloseallpreviousplotsButton.Text = 'Close all previous plots';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown4exhum = uidropdown(app.UIFigure);
            app.DropDown4exhum.Items = {'Auto', 'Manual'};
            app.DropDown4exhum.ValueChangedFcn = createCallbackFcn(app, @DropDown4exhumValueChanged, true);
            app.DropDown4exhum.Value = 'Manual';
            app.InitialgeothermalgradientoCkmEditFieldLabel = uilabel(app.UIFigure);
            app.InitialgeothermalgradientoCkmEditFieldLabel.Text = 'Initial geothermal gradient (oC/km):';
            app.InitialgeothermalgradientoCkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialgeothermalgradientoCkmEditField.ValueChangedFcn = createCallbackFcn(app, @InitialgeothermalgradientoCkmEditFieldValueChanged, true);
            app.InitialgeothermalgradientoCkmEditField.Value = 24;
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown4geothermics = uidropdown(app.UIFigure);
            app.DropDown4geothermics.Items = {'Auto', 'Manual'};
            app.DropDown4geothermics.ValueChangedFcn = createCallbackFcn(app, @DropDown4geothermicsValueChanged, true);
            app.DropDown4geothermics.Value = 'Manual';
            app.TemperatureatthemeanelevationoCEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureatthemeanelevationoCEditFieldLabel.Text = 'Temperature at the mean elevation (oC):';
            app.TemperatureatthemeanelevationoCEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureatthemeanelevationoCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureatthemeanelevationoCEditFieldValueChanged, true);
            app.TemperatureatthemeanelevationoCEditField.Value = -12;
            app.DropDownLabel_2 = uilabel(app.UIFigure);
            app.DropDownLabel_2.Text = 'Drop Down';
            app.DropDown4tempatthemeanelev = uidropdown(app.UIFigure);
            app.DropDown4tempatthemeanelev.Items = {'Auto', 'Manual'};
            app.DropDown4tempatthemeanelev.ValueChangedFcn = createCallbackFcn(app, @DropDown4tempatthemeanelevValueChanged, true);
            app.DropDown4tempatthemeanelev.Value = 'Manual';
            app.MeanelevationmEditFieldLabel = uilabel(app.UIFigure);
            app.MeanelevationmEditFieldLabel.Text = 'Mean elevation (m):';
            app.MeanelevationmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanelevationmEditField.ValueChangedFcn = createCallbackFcn(app, @MeanelevationmEditFieldValueChanged, true);
            app.MeanelevationmEditField.Value = 4020;
            app.DropDown_2Label_2 = uilabel(app.UIFigure);
            app.DropDown_2Label_2.Text = 'Drop Down';
            app.DropDown4mean_elev = uidropdown(app.UIFigure);
            app.DropDown4mean_elev.Items = {'Auto', 'Manual'};
            app.DropDown4mean_elev.ValueChangedFcn = createCallbackFcn(app, @DropDown4mean_elevValueChanged, true);
            app.DropDown4mean_elev.Value = 'Manual';
            app.AutomodetosetinputparametersLabel = uilabel(app.UIFigure);
            app.AutomodetosetinputparametersLabel.Text = 'Auto mode to set input parameters:';
            app.AutomodetosetinputparametersDropDown = uidropdown(app.UIFigure);
            app.AutomodetosetinputparametersDropDown.Items = {'No', 'Yes'};
            app.AutomodetosetinputparametersDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AutomodetosetinputparametersDropDownOpening, true);
            app.AutomodetosetinputparametersDropDown.ValueChangedFcn = createCallbackFcn(app, @AutomodetosetinputparametersDropDownValueChanged, true);
            app.AutomodetosetinputparametersDropDown.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2E_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end